classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        KeluarButton       matlab.ui.control.Button
        HapusButton        matlab.ui.control.Button
        HasilLabel         matlab.ui.control.Label
        PilihGambarButton  matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PilihGambarButton
        function PilihGambarButtonPushed(app, event)
            % === SIMPLIFIED PREDICTION CODE ===
            % Load Model
            modelFile = 'cnn_model.mat';
            if ~isfile(modelFile)
                uialert(app.UIFigure, 'Model cnn_model.mat tidak ditemukan.', 'Error');
                return;
            end
            
            load(modelFile, 'results');
            net = results.net;
            classes = results.classes;
            inputSize = results.inputSize; % [100 100]
            
            % Pilih File Gambar
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg;*.bmp','File Gambar'}, 'Pilih Gambar');
            if isequal(file, 0)
                return;
            end
            fullPath = fullfile(path, file);
            
            % Baca Gambar
            try
                img = imread(fullPath);
                imshow(img, 'Parent', app.UIAxes);
                title(app.UIAxes, sprintf('Input: %s', file), 'Interpreter', 'none');
            catch ME
                uialert(app.UIFigure, sprintf('Error membaca gambar: %s', ME.message), 'Error');
                return;
            end
            
            % Preprocessing SEDERHANA
            try
                % Konversi ke RGB jika grayscale
                if size(img, 3) == 1
                    img = cat(3, img, img, img);
                end
                
                % Resize ke [100 100]
                img = imresize(img, inputSize);
                
                % Pastikan uint8
                if ~isa(img, 'uint8')
                    img = im2uint8(img);
                end
                
                fprintf('Image processed: %s\n', mat2str(size(img)));
                
            catch ME
                uialert(app.UIFigure, sprintf('Error preprocessing: %s', ME.message), 'Error');
                return;
            end
            
            % Klasifikasi
            try
                [pred, scores] = classify(net, img);
                confidence = max(scores) * 100;
                
                % Format hasil
                hasilStr = sprintf('🎯 HASIL: %s\n📊 Confidence: %.1f%%\n', ...
                    upper(char(pred)), confidence);
                
                % Tambah detail scores
                for i = 1:length(classes)
                    hasilStr = sprintf('%s%s: %.1f%%\n', hasilStr, ...
                        char(classes{i}), scores(i)*100);
                end
                
                app.HasilLabel.Text = hasilStr;
                
                fprintf('\n=== HASIL KLASIFIKASI ===\n');
                fprintf('File: %s\n', file);
                fprintf('Prediksi: %s (%.1f%%)\n', char(pred), confidence);
                
            catch ME
                uialert(app.UIFigure, sprintf('Error klasifikasi: %s\nDetail: %s', ME.message, ME.stack(1).name), 'Error');
                return;
            end
        end

        % Button pushed function: HapusButton
        function HapusButtonPushed(app, event)
             cla(app.UIAxes);  % Hapus gambar
             app.HasilLabel.Text = 'Hasil: -';  % Reset teks hasil
        end

        % Button pushed function: KeluarButton
        function KeluarButtonPushed(app, event)
            pilihan = uiconfirm(app.UIFigure, ...
                'Apakah Anda yakin ingin menutup aplikasi?', ...
                'Konfirmasi Keluar', ...
                'Options', {'Ya', 'Tidak'}, ...
                'DefaultOption', 'Tidak', ...
                'CancelOption', 'Tidak');
        
            if strcmp(pilihan, 'Ya')
                delete(app);
            else
                % Batalkan penutupan
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Gambar')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PilihGambarButton = uibutton(app.UIFigure, 'push');
            app.PilihGambarButton.ButtonPushedFcn = createCallbackFcn(app, @PilihGambarButtonPushed, true);
            app.PilihGambarButton.Text = 'Pilih Gambar';
            app.HasilLabel = uilabel(app.UIFigure);
            app.HasilLabel.Text = 'Hasil:';
            app.HapusButton = uibutton(app.UIFigure, 'push');
            app.HapusButton.ButtonPushedFcn = createCallbackFcn(app, @HapusButtonPushed, true);
            app.HapusButton.Text = 'Hapus';
            app.KeluarButton = uibutton(app.UIFigure, 'push');
            app.KeluarButton.ButtonPushedFcn = createCallbackFcn(app, @KeluarButtonPushed, true);
            app.KeluarButton.Text = 'Keluar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end