classdef Diabetic_retinopathy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AreaEditField                  matlab.ui.control.NumericEditField
        AreaEditFieldLabel             matlab.ui.control.Label
        DiagnosisTextArea              matlab.ui.control.TextArea
        DiagnosisTextAreaLabel         matlab.ui.control.Label
        MenuPanel                      matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        BrowserButton                  matlab.ui.control.Button
        ExitButton                     matlab.ui.control.Button
        AboutButton                    matlab.ui.control.Button
        ClearButton                    matlab.ui.control.Button
        PreprocessingButton            matlab.ui.control.Button
        AutomaticDetectionofDiabeticRetinopathyEyeFundusImagesLabel  matlab.ui.control.Label
        Siska2020UTeMLabel             matlab.ui.control.Label
        StandardDeviationEditField     matlab.ui.control.NumericEditField
        StandardDeviationEditFieldLabel  matlab.ui.control.Label
        MeanEditField                  matlab.ui.control.NumericEditField
        MeanEditFieldLabel             matlab.ui.control.Label
        NumberOffPixelsEditField       matlab.ui.control.NumericEditField
        NumberOffPixelsEditFieldLabel  matlab.ui.control.Label
        NumberOnPixelsEditField        matlab.ui.control.NumericEditField
        NumberOnPixelsEditFieldLabel   matlab.ui.control.Label
        FeaturesLabel                  matlab.ui.control.Label
        PreprocessingLabel             matlab.ui.control.Label
        ImageAcquisitionLabel          matlab.ui.control.Label
        UIAxes5                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowserButton
        function BrowserButtonPushed(app, event)
%             [filename, filepath] = uigetfile({'*.*'},'File Selector');
%             fullname= [filepath,filename];
%             imshow (strcat (filepath,filename), 'parent', app.UIAxes);    
%             app.Image.ImageSource=fullname;


%  The button Browser for input images to UIAxes
        global gmb
        [FileName, FilePath]=uigetfile({'*.*'}, 'Select a file'); 
        if isnumeric(FileName); return; end 
        gmb= imread(fullfile(FilePath, FileName)); 
        imshow(gmb, 'Parent' , app.UIAxes); 
            
        end

        % Button pushed function: PreprocessingButton
        function PreprocessingButtonPushed(app, event)
%  for preprocessing images, grayscale, filtering, Histogram Equalisation,
%  and thresholding 

%  First we start with grayscale 
      global gmb
      column=64; row=64;
      img_rsz=imresize(gmb,[row column]);
      img_grayscale= rgb2gray(img_rsz); 
      imshow (img_grayscale, 'Parent' , app.UIAxes2); 
      
%  Second Filtering 
      img_filt= medfilt2 (img_grayscale);
      imshow (img_filt, 'Parent' , app.UIAxes3);
      
%  Three Histogram Equalisation 
      img_hist=adapthisteq(img_filt);
      imshow (img_hist, 'Parent', app.UIAxes4); 
 
%    Four Thresholding   
      level=graythresh(img_hist);
      img_bin=imbinarize(img_hist,level);
      imshow (img_bin,'parent', app.UIAxes5); 
      
       th= 55;
       On=0; Off=0;  
 %    untuk inisiasi variable pada number on pixel dan off pixel 
      
       Mean=mean(img_grayscale,'all');
       Std=std2(img_grayscale);
       area=bwarea(img_grayscale);
 
%   For loop used for detecting black and white points in the picture.
           
          for i=1:row
             for k=1:column
                  if img_grayscale(i,k) >= th
                      On = On+1;
                  else
                      Off = Off+1;
                  end
             end     
          end
                 app.NumberOnPixelsEditField.Value= On;
                 app.NumberOffPixelsEditField.Value= Off;
                 app.MeanEditField.Value= Mean;
                 app.StandardDeviationEditField.Value=Std;
                 app.AreaEditField.Value=area;
                 
% KNN
    load_model = '/Users/siskaatmawanoktavia/Downloads/THESIS EDITING /APP DIABETIC RETINOPATHY/KNN_Classification_data_siska.mat';
    KNN_model = loadLearnerForCoder(load_model);
    dt_img = table (On,Off,Mean,Std);
    
    label = predict(KNN_model,dt_img);
    app.DiagnosisTextArea.Value = label;

% %  SVM
  % load_model = '/Users/siskaatmawanoktavia/Downloads/THESIS EDITING /APP DIABETIC RETINOPATHY/KNN_Classification_data_siska.mat';ata_siska.mat';
 %  SVMmodel = loadLearnerForCoder(load_model);
  % dt_img = table (On,Off,Mean,Std);
   %label = predict(SVMmodel,dt_img);
    % app.ResultSVMTextArea.Value = label;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            quit
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
%              cla(app.UIAxes,'reset');
               app.UIAxes.cla;
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            web('https://mathworks.com', '-browser')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Tag = 'axes1';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Grayscale')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Filtering')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Histogram Equalisation')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Thresholding')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.ImageAcquisitionLabel = uilabel(app.UIFigure);
            app.ImageAcquisitionLabel.Text = 'Image Acquisition';
            app.PreprocessingLabel = uilabel(app.UIFigure);
            app.PreprocessingLabel.Text = 'Pre-processing';
            app.FeaturesLabel = uilabel(app.UIFigure);
            app.FeaturesLabel.Text = 'Features';
            app.NumberOnPixelsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberOnPixelsEditFieldLabel.Text = 'Number On Pixels';
            app.NumberOnPixelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberOffPixelsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberOffPixelsEditFieldLabel.Text = 'Number Off Pixels';
            app.NumberOffPixelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MeanEditFieldLabel = uilabel(app.UIFigure);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.UIFigure, 'numeric');
            app.StandardDeviationEditFieldLabel = uilabel(app.UIFigure);
            app.StandardDeviationEditFieldLabel.Text = 'Standard Deviation';
            app.StandardDeviationEditField = uieditfield(app.UIFigure, 'numeric');
            app.Siska2020UTeMLabel = uilabel(app.UIFigure);
            app.Siska2020UTeMLabel.Text = {'@Siska2020, '; 'UTeM'};
            app.AutomaticDetectionofDiabeticRetinopathyEyeFundusImagesLabel = uilabel(app.UIFigure);
            app.AutomaticDetectionofDiabeticRetinopathyEyeFundusImagesLabel.Text = 'Automatic Detection of Diabetic Retinopathy Eye Fundus Images ';
            app.MenuPanel = uipanel(app.UIFigure);
            app.MenuPanel.Title = 'Menu';
            app.GridLayout = uigridlayout(app.MenuPanel);
            app.PreprocessingButton = uibutton(app.GridLayout, 'push');
            app.PreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessingButtonPushed, true);
            app.PreprocessingButton.Text = 'Preprocessing';
            app.ClearButton = uibutton(app.GridLayout, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.AboutButton = uibutton(app.GridLayout, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            app.ExitButton = uibutton(app.GridLayout, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.BrowserButton = uibutton(app.GridLayout, 'push');
            app.BrowserButton.ButtonPushedFcn = createCallbackFcn(app, @BrowserButtonPushed, true);
            app.BrowserButton.Tag = 'Browser';
            app.BrowserButton.Text = 'Browser';
            app.DiagnosisTextAreaLabel = uilabel(app.UIFigure);
            app.DiagnosisTextAreaLabel.Text = 'Diagnosis';
            app.DiagnosisTextArea = uitextarea(app.UIFigure);
            app.AreaEditFieldLabel = uilabel(app.UIFigure);
            app.AreaEditFieldLabel.Text = 'Area';
            app.AreaEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Diabetic_retinopathy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end