classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ExitButton               matlab.ui.control.Button
        ClearButton              matlab.ui.control.Button
        HasilSVMLabel            matlab.ui.control.Label
        HasilKNNLabel            matlab.ui.control.Label
        EditField5               matlab.ui.control.NumericEditField
        EditField5Label          matlab.ui.control.Label
        EditField4               matlab.ui.control.NumericEditField
        EditField4Label          matlab.ui.control.Label
        EditField3               matlab.ui.control.NumericEditField
        EditField3Label          matlab.ui.control.Label
        EditField2               matlab.ui.control.NumericEditField
        EditField2Label          matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        EditFieldLabel           matlab.ui.control.Label
        PilihgambarButton        matlab.ui.control.Button
        DiagnosaButton           matlab.ui.control.Button
        PreprocessingStepsPanel  matlab.ui.container.Panel
        UIAxes3_4                matlab.ui.control.UIAxes
        UIAxes3_3                matlab.ui.control.UIAxes
        UIAxes3_2                matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img            % Gambar yang dipilih
        fitur          % Fitur ekstraksi dari gambar
        stdThresh      % Standar deviasi hasil threshold
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DiagnosaButton
        function DiagnosaButtonPushed(app, event)
            % --- 1. Grayscale
            grayImg = rgb2gray(app.img);
            imshow(grayImg, 'Parent', app.UIAxes3);
            
            % --- 2. Filtering (Gaussian)
            filterImg = imgaussfilt(grayImg, 2);
            imshow(filterImg, 'Parent', app.UIAxes3_2);
            
            % --- 3. Histogram Equalization
            histeqImg = histeq(filterImg);
            imshow(histeqImg, 'Parent', app.UIAxes3_3);
            
            % --- 4. Thresholding
            bw = imbinarize(histeqImg);
            imshow(bw, 'Parent', app.UIAxes3_4);
             % Load model
            load('modelKNN.mat', 'modelKNN');
            load('modelSVM.mat', 'modelSVM');

            % === KNN Manual
            X_train = modelKNN.X_train;
            y_train = modelKNN.y_train;
            k = modelKNN.k;

            dists = sqrt(sum((X_train - app.fitur).^2, 2));
            [~, idx] = sort(dists);
            k_labels = y_train(idx(1:k));
            hasil_knn = mode(categorical(k_labels));

            app.HasilKNNLabel.Text = "KNN: " + string(hasil_knn);

            % === SVM Manual
            X_norm = (app.fitur - modelSVM.mean) ./ modelSVM.std;
            prediksi = sign(X_norm * modelSVM.w + modelSVM.b);

            if prediksi == 1
                hasil_svm = "sakit";
            else
                hasil_svm = "sehat";
            end

            app.HasilSVMLabel.Text = "SVM: " + hasil_svm;
        end

        % Button pushed function: PilihgambarButton
        function PilihgambarButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png'}, 'Pilih Gambar');
            if isequal(file,0)
                return;
            end

            imgPath = fullfile(path, file);
            app.img = imread(imgPath);

            % Tampilkan gambar asli
            imshow(app.img, 'Parent', app.UIAxes);

            % Grayscale
            if size(app.img,3) == 3
                gray = rgb2gray(app.img);
            else
                gray = app.img;
            end

            % Filtering (Median)
            filtered = medfilt2(gray);

            % Histogram
            histCounts = imhist(filtered);
            bar(app.UIAxes2, histCounts);

            % Thresholding (Otsu)
            level = graythresh(filtered);
            bw = imbinarize(filtered, level);

            % Ekstraksi fitur
            ON  = sum(bw(:));                     % jumlah piksel putih
            OFF = sum(~bw(:));                    % jumlah piksel hitam
            Mean = mean(filtered(:));             % rata-rata nilai piksel
            Std  = std(double(filtered(:)));      % standar deviasi citra
            StdThresh = std(double(bw(:)));       % std dev dari hasil threshold

            % Simpan fitur
            app.fitur = [ON, OFF, Mean, Std];
            app.stdThresh = StdThresh;

            % Tampilkan ke EditField
            app.EditField.Value  = ON;
            app.EditField2.Value = OFF;
            app.EditField3.Value = Mean;
            app.EditField4.Value = Std;
            app.EditField5.Value = StdThresh;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
           selection = uiconfirm(app.UIFigure, ...
                'Apakah Anda yakin ingin menghapus data/form?', ...
                'Konfirmasi Clear', ...
                'Options', {'Ya', 'Tidak'}, ...
                'DefaultOption', 'Tidak', ...
                'CancelOption', 'Tidak');
        
            if strcmp(selection, 'Ya')
                % Hapus gambar
                cla(app.UIAxes);
                cla(app.UIAxes2);
        
                % Kosongkan variabel internal
                app.img = [];
                app.fitur = [];
                app.stdThresh = [];

                % Kosongkan semua axes preprocessing
                cla(app.axesGray);
                cla(app.axesFilter);
                cla(app.axesHistEq);
                cla(app.axesThresh);
        
                % Kosongkan nilai edit field
                app.EditField.Value = 0;
                app.EditField2.Value = 0;
                app.EditField3.Value = 0;
                app.EditField4.Value = 0;
                app.EditField5.Value = 0;
        
                % Kosongkan label hasil klasifikasi
                app.HasilKNNLabel.Text = 'Hasil KNN:';
                app.HasilSVMLabel.Text = 'Hasil SVM:';
            end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            selection = uiconfirm(app.UIFigure, ...
                'Apakah Anda yakin ingin keluar?', ...
                'Konfirmasi Keluar', ...
                'Options', {'Ya', 'Tidak'}, ...
                'DefaultOption', 'Tidak', ...
                'CancelOption', 'Tidak');
        
            if strcmp(selection, 'Ya')
                delete(app.UIFigure);  % Menutup aplikasi
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Gambar Asli')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'axesGambar';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Histogram')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Tag = 'axesHistogram';
            app.PreprocessingStepsPanel = uipanel(app.UIFigure);
            app.PreprocessingStepsPanel.Title = 'Preprocessing Steps';
            app.UIAxes3 = uiaxes(app.PreprocessingStepsPanel);
            title(app.UIAxes3, 'Grayscale')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.Tag = 'axesGray';
            app.UIAxes3_2 = uiaxes(app.PreprocessingStepsPanel);
            title(app.UIAxes3_2, 'Filtering (Gaussian)')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3_2.Tag = 'axesFilter';
            app.UIAxes3_3 = uiaxes(app.PreprocessingStepsPanel);
            title(app.UIAxes3_3, 'Histogram Equalization')
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            zlabel(app.UIAxes3_3, 'Z')
            app.UIAxes3_3.Tag = 'axesHistEq';
            app.UIAxes3_4 = uiaxes(app.PreprocessingStepsPanel);
            title(app.UIAxes3_4, 'Thresholding')
            xlabel(app.UIAxes3_4, 'X')
            ylabel(app.UIAxes3_4, 'Y')
            zlabel(app.UIAxes3_4, 'Z')
            app.UIAxes3_4.Tag = 'axesThresh';
            app.DiagnosaButton = uibutton(app.UIFigure, 'push');
            app.DiagnosaButton.ButtonPushedFcn = createCallbackFcn(app, @DiagnosaButtonPushed, true);
            app.DiagnosaButton.Tag = 'btnDiagnosa';
            app.DiagnosaButton.Text = 'Diagnosa';
            app.PilihgambarButton = uibutton(app.UIFigure, 'push');
            app.PilihgambarButton.ButtonPushedFcn = createCallbackFcn(app, @PilihgambarButtonPushed, true);
            app.PilihgambarButton.Tag = 'btnPilihGambar';
            app.PilihgambarButton.Text = 'Pilih gambar';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Tag = 'editON';
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2.Tag = 'editOFF';
            app.EditField3Label = uilabel(app.UIFigure);
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3.Tag = 'editMean';
            app.EditField4Label = uilabel(app.UIFigure);
            app.EditField4Label.Text = 'Edit Field4';
            app.EditField4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4.Tag = 'editStd';
            app.EditField5Label = uilabel(app.UIFigure);
            app.EditField5Label.Text = 'Edit Field5';
            app.EditField5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField5.Tag = 'editStdThresh';
            app.HasilKNNLabel = uilabel(app.UIFigure);
            app.HasilKNNLabel.Tag = 'labelKNN';
            app.HasilKNNLabel.Text = 'Hasil KNN';
            app.HasilSVMLabel = uilabel(app.UIFigure);
            app.HasilSVMLabel.Tag = 'labelSVM';
            app.HasilSVMLabel.Text = 'Hasil SVM';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end